classdef ColocAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComputecolocalizationparametersTab  matlab.ui.container.Tab
        FolderwithImagesEditFieldLabel  matlab.ui.control.Label
        FolderwithImagesEditField       matlab.ui.control.EditField
        Thresholdvalueformanual01Label_2  matlab.ui.control.Label
        Thresholdvalueformanual01Spinner  matlab.ui.control.Spinner
        FilteringmethodListBoxLabel     matlab.ui.control.Label
        FilteringmethodListBox          matlab.ui.control.ListBox
        SavefilteredimagesEditFieldLabel  matlab.ui.control.Label
        SavefilteredimagesEditField     matlab.ui.control.EditField
        FolderforsavingresultsEditFieldLabel  matlab.ui.control.Label
        FolderforsavingresultsEditField  matlab.ui.control.EditField
        RunButton                       matlab.ui.control.StateButton
        DistancetoclosestneighbourCheckBox  matlab.ui.control.CheckBox
        PearsonsCoefficientCheckBox     matlab.ui.control.CheckBox
        PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox  matlab.ui.control.CheckBox
        ManderscoefficientsCheckBox     matlab.ui.control.CheckBox
        Button                          matlab.ui.control.StateButton
        SpearmancoefficientCheckBox     matlab.ui.control.CheckBox
        HelpopenmanualButton            matlab.ui.control.Button
        SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox  matlab.ui.control.CheckBox
        RunrandomspotshufflingtestsTab  matlab.ui.container.Tab
        RunButton_2                     matlab.ui.control.StateButton
        DistancetoclosestneighbourCheckBox_2  matlab.ui.control.CheckBox
        PearsonsCoefficientCheckBox_2   matlab.ui.control.CheckBox
        PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2  matlab.ui.control.CheckBox
        ManderscoefficientsCheckBox_2   matlab.ui.control.CheckBox
        Button_3                        matlab.ui.control.StateButton
        FolderwithImagesEditField_2Label  matlab.ui.control.Label
        FolderwithImagesEditField_2     matlab.ui.control.EditField
        SavefilteredimagesEditField_2Label  matlab.ui.control.Label
        SavefilteredimagesEditField_2   matlab.ui.control.EditField
        Thresholdvalueformanual01Label  matlab.ui.control.Label
        Thresholdvalueformanual01Spinner_2  matlab.ui.control.Spinner
        FolderforsavingresultsforrandomshuffledimagesEditFieldLabel  matlab.ui.control.Label
        FolderforsavingresultsforrandomshuffledimagesEditField  matlab.ui.control.EditField
        SaveimagesafterrandomspotshufflingEditFieldLabel  matlab.ui.control.Label
        SaveimagesafterrandomspotshufflingEditField  matlab.ui.control.EditField
        NumberofrandomtestsSpinnerLabel  matlab.ui.control.Label
        NumberofrandomtestsSpinner      matlab.ui.control.Spinner
        FilteringmethodListBox_2Label   matlab.ui.control.Label
        FilteringmethodListBox_2        matlab.ui.control.ListBox
        SpearmancoefficientCheckBox_2   matlab.ui.control.CheckBox
        SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2  matlab.ui.control.CheckBox
        AdvancedparametersTab           matlab.ui.container.Tab
        Channel11red2green3blueLabel    matlab.ui.control.Label
        Channel11red2green3blueEditField  matlab.ui.control.NumericEditField
        Channel21red2green3blueEditFieldLabel  matlab.ui.control.Label
        Channel21red2green3blueEditField  matlab.ui.control.NumericEditField
        SizeoftheaveragingareamedianfilteringpxEditFieldLabel  matlab.ui.control.Label
        SizeoftheaveragingareamedianfilteringpxEditField  matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel       matlab.ui.control.Label
        PixelsizenmEditField            matlab.ui.control.NumericEditField
        Button_2                        matlab.ui.control.StateButton
        PixelsizeinnmEditFieldLabel_2   matlab.ui.control.Label
        Channeltodeterminethecellboundary1red2green3blueEditFieldLabel  matlab.ui.control.Label
        Channeltodeterminethecellboundary1red2green3blueEditField  matlab.ui.control.NumericEditField
        LengthoftheboxsidefordeterminingcellboundarypxLabel  matlab.ui.control.Label
        Channeltodeterminethecellboundary1red2green3blueEditField_2  matlab.ui.control.NumericEditField
        Densitycorrectionfactorforshuffledchannel02Label  matlab.ui.control.Label
        Channeltodeterminethecellboundary1red2green3blueEditField_3  matlab.ui.control.NumericEditField
        Thresholdforthedensityofthespotstodeterminecellboundary01Label  matlab.ui.control.Label
        Channeltodeterminethecellboundary1red2green3blueEditField_4  matlab.ui.control.NumericEditField
    end


    properties (Access = public)
       % FolderWithFiles % Description
    end
    
    properties (Access = private)
        FolderWithFiles; % folder from which images to analyse are loaded
        ChosenFilteringMethod; % Chosen by user filtering method
        ManualThreshold; % Value from the spinner
        FolderForFilteredImages; % Folder where filtered images will be saved
        FolderForResults; % Folder where results will be saved
        Pearson; % Binary number for computing Pearson coefficient
        PearsonNonZero; % Binary number for computing Pearson coefficient in pixels which are non zero in both channels
        Distance; % Binary number for computing  distance to closest spot from channel 1 to channel 2
        Manders; % Binary number for computing  Manders coefficients
        %here are values in parameters tab
        MainChannel; % 1-for red, 2- for green and 3 for blue
        SecChannel; % 1-for red, 2- for green and 3 for blue
        BoxSizeMedian; % size of the box around the spot where median is computed
        PixelSize; % transition between pixels and nanometers
        Spearman; % Binary number for computing  Spearman coefficients
        SpearmanNonZero; % Binary number for computing  Spearman coefficient fpr pixels which are non zero in both channels
        
        %% below all values are for random shffling tests
        FolderWithFiles_Ran; % folder from which images to analyse are loaded
        ChosenFilteringMethod_Ran; % Chosen by user filtering method
        ManualThreshold_Ran; % Value from the spinner
        FolderForFilteredImages_Ran; % Folder where filtered images will be saved
        FolderForShuffledImages_Ran; % Folder where images after the spot shuffling will be saved
        NumberTests_Ran; % Number of random tests 
        FolderForResults_Ran; % Folder where results will be saved
        Pearson_Ran; % Binary number for computing Pearson coefficient
        PearsonNonZero_Ran; % Binary number for computing Pearson coefficient in pixels which are non zero in both channels
        Distance_Ran; % Binary number for computing  distance to closest spot from channel 1 to channel 2
        Manders_Ran; % Binary number for computing  Manders coefficients
        BoxSize_Ran; % Value of the box length to determine the cell boundaries
        ChannelDensity_Ran;  % 1-for red, 2- for green and 3 for blue
        DensityCorrections_Ran; %number from 0 to 2 that may change density of shuffled spots
        DensityThreshold_Ran; % relative threshold to leave only the areas with high density spots
        Spearman_Ran; % Binary number for computing Spearman coefficient for ramdom shuffling test
        %TextArea % For manual
        %HelpopenmanualButton
        SpearmanNonZero_Ran % % Binary number for computing  Spearman coefficient fpr pixels which are non zero in both channels for ramdom shuffling test
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FolderwithImagesEditField
        function FolderwithImagesEditFieldValueChanged(app, event)
            app.FolderWithFiles = app.FolderwithImagesEditField.Value; 
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value ~= 0
                FolderwithImagesEditFieldValueChanged(app, event)
                FilteringmethodListBoxValueChanged(app, event)
                Thresholdvalueformanual01SpinnerValueChanged(app, event)
                SavefilteredimagesEditFieldValueChanged(app, event)
                FolderforsavingresultsEditFieldValueChanged(app, event)
                PearsonsCoefficientCheckBoxValueChanged(app, event)
                PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged(app, event)
                DistancetoclosestneighbourCheckBoxValueChanged(app, event)
                ManderscoefficientsCheckBoxValueChanged(app, event)
                Channel11red2green3blueEditFieldValueChanged(app, event)
                Channel21red2green3blueEditFieldValueChanged(app, event)
                SizeoftheaveragingareamedianfilteringpxEditFieldValueChanged(app, event)
                PixelsizenmEditFieldValueChanged(app, event)
                SpearmancoefficientCheckBoxValueChanged(app, event)
                SpearmancoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged(app, event)
                app.SpearmanNonZero
                MainScript(app.FolderWithFiles, app.ChosenFilteringMethod, app.ManualThreshold, app.FolderForFilteredImages, app.FolderForResults, app.Pearson, app.PearsonNonZero, app.Distance, app.Manders, app.MainChannel, app.SecChannel, app.BoxSizeMedian, app.PixelSize, app.Spearman, app.SpearmanNonZero)
                msgbox('Computing colocalization parameters is completed!')
            end
        end

        % Value changed function: FilteringmethodListBox
        function FilteringmethodListBoxValueChanged(app, event)
            app.ChosenFilteringMethod = app.FilteringmethodListBox.Value
            
        end

        % Value changed function: Thresholdvalueformanual01Spinner
        function Thresholdvalueformanual01SpinnerValueChanged(app, event)
            app.ManualThreshold = app.Thresholdvalueformanual01Spinner.Value;
            
        end

        % Value changed function: SavefilteredimagesEditField
        function SavefilteredimagesEditFieldValueChanged(app, event)
            app.FolderForFilteredImages = app.SavefilteredimagesEditField.Value;
            
        end

        % Value changed function: FolderforsavingresultsEditField
        function FolderforsavingresultsEditFieldValueChanged(app, event)
            app.FolderForResults = app.FolderforsavingresultsEditField.Value;
            
        end

        % Value changed function: PearsonsCoefficientCheckBox
        function PearsonsCoefficientCheckBoxValueChanged(app, event)
            app.Pearson = app.PearsonsCoefficientCheckBox.Value;
        end

        % Value changed function: 
        % PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox
        function PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged(app, event)
            app.PearsonNonZero = app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox.Value;
            
        end

        % Value changed function: DistancetoclosestneighbourCheckBox
        function DistancetoclosestneighbourCheckBoxValueChanged(app, event)
            app.Distance = app.DistancetoclosestneighbourCheckBox.Value;
            
        end

        % Value changed function: ManderscoefficientsCheckBox
        function ManderscoefficientsCheckBoxValueChanged(app, event)
            app.Manders = app.ManderscoefficientsCheckBox.Value;
            
        end

        % Value changed function: Channel11red2green3blueEditField
        function Channel11red2green3blueEditFieldValueChanged(app, event)
            app.MainChannel = app.Channel11red2green3blueEditField.Value;  
        end

        % Value changed function: Channel21red2green3blueEditField
        function Channel21red2green3blueEditFieldValueChanged(app, event)
            app.SecChannel = app.Channel21red2green3blueEditField.Value;
        end

        % Value changed function: 
        % SizeoftheaveragingareamedianfilteringpxEditField
        function SizeoftheaveragingareamedianfilteringpxEditFieldValueChanged(app, event)
            app.BoxSizeMedian = app.SizeoftheaveragingareamedianfilteringpxEditField.Value;
        end

        % Value changed function: PixelsizenmEditField
        function PixelsizenmEditFieldValueChanged(app, event)
            app.PixelSize = app.PixelsizenmEditField.Value;
        end

        % Callback function
        function LogmessageswindowTextAreaValueChanged(app, event)
            
            
        end

        % Value changed function: FolderwithImagesEditField_2
        function FolderwithImagesEditField_2ValueChanged(app, event)
            app.FolderWithFiles_Ran = app.FolderwithImagesEditField_2.Value;       
        end

        % Value changed function: FilteringmethodListBox_2
        function FilteringmethodListBox_2ValueChanged(app, event)
            app.ChosenFilteringMethod_Ran = app.FilteringmethodListBox_2.Value;
        end

        % Value changed function: Thresholdvalueformanual01Spinner_2
        function Thresholdvalueformanual01Spinner_2ValueChanged(app, event)
            app.ManualThreshold_Ran = app.Thresholdvalueformanual01Spinner_2.Value; 
        end

        % Value changed function: SavefilteredimagesEditField_2
        function SavefilteredimagesEditField_2ValueChanged(app, event)
            app.FolderForFilteredImages_Ran = app.SavefilteredimagesEditField_2.Value;
        end

        % Value changed function: NumberofrandomtestsSpinner
        function NumberofrandomtestsSpinnerValueChanged(app, event)
            app.NumberTests_Ran = app.NumberofrandomtestsSpinner.Value;
        end

        % Value changed function: 
        % SaveimagesafterrandomspotshufflingEditField
        function SaveimagesafterrandomspotshufflingEditFieldValueChanged(app, event)
            app.FolderForShuffledImages_Ran = app.SaveimagesafterrandomspotshufflingEditField.Value;     
        end

        % Value changed function: 
        % FolderforsavingresultsforrandomshuffledimagesEditField
        function FolderforsavingresultsforrandomshuffledimagesEditFieldValueChanged(app, event)
             app.FolderForResults_Ran = app.FolderforsavingresultsforrandomshuffledimagesEditField.Value;
        end

        % Value changed function: PearsonsCoefficientCheckBox_2
        function PearsonsCoefficientCheckBox_2ValueChanged(app, event)
            app.Pearson_Ran = app.PearsonsCoefficientCheckBox_2.Value;         
        end

        % Value changed function: 
        % PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2
        function PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged(app, event)
            app.PearsonNonZero_Ran = app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2.Value;         
        end

        % Value changed function: 
        % DistancetoclosestneighbourCheckBox_2
        function DistancetoclosestneighbourCheckBox_2ValueChanged(app, event)
            app.Distance_Ran = app.DistancetoclosestneighbourCheckBox_2.Value;       
        end

        % Value changed function: ManderscoefficientsCheckBox_2
        function ManderscoefficientsCheckBox_2ValueChanged(app, event)
            app.Manders_Ran = app.ManderscoefficientsCheckBox_2.Value;
        end

        % Value changed function: RunButton_2
        function RunButton_2ValueChanged(app, event)
            value = app.RunButton_2.Value;
            if value ~= 0
                FolderwithImagesEditField_2ValueChanged(app, event)
                FilteringmethodListBox_2ValueChanged(app, event)
                Thresholdvalueformanual01Spinner_2ValueChanged(app, event)
                SavefilteredimagesEditField_2ValueChanged(app, event)
                NumberofrandomtestsSpinnerValueChanged(app, event)
                SaveimagesafterrandomspotshufflingEditFieldValueChanged(app, event)
                FolderforsavingresultsforrandomshuffledimagesEditFieldValueChanged(app, event)
                PearsonsCoefficientCheckBox_2ValueChanged(app, event)
                PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged(app, event)
                DistancetoclosestneighbourCheckBox_2ValueChanged(app, event)
                ManderscoefficientsCheckBox_2ValueChanged(app, event)
                Channeltodeterminethecellboundary1red2green3blueEditField_3ValueChanged(app, event)
                Channel11red2green3blueEditFieldValueChanged(app, event)
                Channel21red2green3blueEditFieldValueChanged(app, event)
                SizeoftheaveragingareamedianfilteringpxEditFieldValueChanged(app, event)
                PixelsizenmEditFieldValueChanged(app, event)
                Channeltodeterminethecellboundary1red2green3blueEditFieldValueChanged(app, event)
                Channeltodeterminethecellboundary1red2green3blueEditField_2ValueChanged(app, event)
                Channeltodeterminethecellboundary1red2green3blueEditField_3ValueChanged(app, event)
                SaveimagesafterrandomspotshufflingEditFieldValueChanged(app, event)
                Channeltodeterminethecellboundary1red2green3blueEditField_4ValueChanged(app, event)
                FolderforsavingresultsforrandomshuffledimagesEditFieldValueChanged(app, event)
                SpearmancoefficientCheckBox_2ValueChanged(app, event)
                SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged(app, event)
                
                MainScriptRandomShuffle(app.FolderWithFiles_Ran, app.ChosenFilteringMethod_Ran, app.ManualThreshold_Ran, app.FolderForFilteredImages_Ran, app.FolderForResults_Ran, app.Pearson_Ran, app.PearsonNonZero_Ran, app.Distance_Ran, app.Manders_Ran, app.MainChannel, app.SecChannel, app.BoxSizeMedian, app.PixelSize, app.FolderForShuffledImages_Ran, app.NumberTests_Ran, app.ChannelDensity_Ran, app.BoxSize_Ran, app.DensityCorrections_Ran, app.DensityThreshold_Ran, app.Spearman_Ran, app.SpearmanNonZero_Ran)
                msgbox('Random shuffling test is completed!')
            end
        end

        % Value changed function: 
        % Channeltodeterminethecellboundary1red2green3blueEditField
        function Channeltodeterminethecellboundary1red2green3blueEditFieldValueChanged(app, event)
            app.ChannelDensity_Ran = app.Channeltodeterminethecellboundary1red2green3blueEditField.Value;
        end

        % Value changed function: 
        % Channeltodeterminethecellboundary1red2green3blueEditField_2
        function Channeltodeterminethecellboundary1red2green3blueEditField_2ValueChanged(app, event)
            app.BoxSize_Ran = app.Channeltodeterminethecellboundary1red2green3blueEditField_2.Value; 
        end

        % Value changed function: 
        % Channeltodeterminethecellboundary1red2green3blueEditField_3
        function Channeltodeterminethecellboundary1red2green3blueEditField_3ValueChanged(app, event)
            app.DensityCorrections_Ran = app.Channeltodeterminethecellboundary1red2green3blueEditField_3.Value;      
        end

        % Value changed function: 
        % Channeltodeterminethecellboundary1red2green3blueEditField_4
        function Channeltodeterminethecellboundary1red2green3blueEditField_4ValueChanged(app, event)
            app.DensityThreshold_Ran = app.Channeltodeterminethecellboundary1red2green3blueEditField_4.Value;
        end

        % Value changed function: SpearmancoefficientCheckBox
        function SpearmancoefficientCheckBoxValueChanged(app, event)
            app.Spearman = app.SpearmancoefficientCheckBox.Value;
            
        end

        % Value changed function: SpearmancoefficientCheckBox_2
        function SpearmancoefficientCheckBox_2ValueChanged(app, event)
            app.Spearman_Ran = app.SpearmancoefficientCheckBox_2.Value;
            
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            winopen('ColocAnalyzer Manual.pdf')
        end

        % Button pushed function: HelpopenmanualButton
        function HelpopenmanualButtonPushed(app, event)
            winopen('ColocAnalyzer Manual.pdf')
        end

        % Value changed function: 
        % SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox
        function SpearmancoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged(app, event)
            app.SpearmanNonZero = app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox.Value;
        end

        % Value changed function: 
        % SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2
        function SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged(app, event)
            app.SpearmanNonZero_Ran = app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComputecolocalizationparametersTab = uitab(app.TabGroup);
            app.ComputecolocalizationparametersTab.Title = 'Compute colocalization parameters';
            app.FolderwithImagesEditFieldLabel = uilabel(app.ComputecolocalizationparametersTab);
            app.FolderwithImagesEditFieldLabel.Text = 'Folder with Images';
            app.FolderwithImagesEditField = uieditfield(app.ComputecolocalizationparametersTab, 'text');
            app.FolderwithImagesEditField.ValueChangedFcn = createCallbackFcn(app, @FolderwithImagesEditFieldValueChanged, true);
            app.Thresholdvalueformanual01Label_2 = uilabel(app.ComputecolocalizationparametersTab);
            app.Thresholdvalueformanual01Label_2.Text = 'Threshold value for manual [0-1]';
            app.Thresholdvalueformanual01Spinner = uispinner(app.ComputecolocalizationparametersTab);
            app.Thresholdvalueformanual01Spinner.Step = 0.01;
            app.Thresholdvalueformanual01Spinner.Limits = [0 1];
            app.Thresholdvalueformanual01Spinner.ValueChangedFcn = createCallbackFcn(app, @Thresholdvalueformanual01SpinnerValueChanged, true);
            app.FilteringmethodListBoxLabel = uilabel(app.ComputecolocalizationparametersTab);
            app.FilteringmethodListBoxLabel.Text = 'Filtering method';
            app.FilteringmethodListBox = uilistbox(app.ComputecolocalizationparametersTab);
            app.FilteringmethodListBox.Items = {'Manual', 'Otsu''s', 'Costes', 'Median', 'Median + Otsu''s', 'No filtering'};
            app.FilteringmethodListBox.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodListBoxValueChanged, true);
            app.FilteringmethodListBox.Value = 'Manual';
            app.SavefilteredimagesEditFieldLabel = uilabel(app.ComputecolocalizationparametersTab);
            app.SavefilteredimagesEditFieldLabel.Text = 'Save filtered images';
            app.SavefilteredimagesEditField = uieditfield(app.ComputecolocalizationparametersTab, 'text');
            app.SavefilteredimagesEditField.ValueChangedFcn = createCallbackFcn(app, @SavefilteredimagesEditFieldValueChanged, true);
            app.FolderforsavingresultsEditFieldLabel = uilabel(app.ComputecolocalizationparametersTab);
            app.FolderforsavingresultsEditFieldLabel.Text = 'Folder for saving results';
            app.FolderforsavingresultsEditField = uieditfield(app.ComputecolocalizationparametersTab, 'text');
            app.FolderforsavingresultsEditField.ValueChangedFcn = createCallbackFcn(app, @FolderforsavingresultsEditFieldValueChanged, true);
            app.RunButton = uibutton(app.ComputecolocalizationparametersTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run!';
            app.DistancetoclosestneighbourCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.DistancetoclosestneighbourCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistancetoclosestneighbourCheckBoxValueChanged, true);
            app.DistancetoclosestneighbourCheckBox.Text = 'Distance to closest neighbour';
            app.PearsonsCoefficientCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.PearsonsCoefficientCheckBox.ValueChangedFcn = createCallbackFcn(app, @PearsonsCoefficientCheckBoxValueChanged, true);
            app.PearsonsCoefficientCheckBox.Text = 'Pearson''s Coefficient';
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged, true);
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox.Text = 'Pearson''s Coefficient for "non-zero" in both channels pixels';
            app.ManderscoefficientsCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.ManderscoefficientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManderscoefficientsCheckBoxValueChanged, true);
            app.ManderscoefficientsCheckBox.Text = 'Manders coefficients';
            app.Button = uibutton(app.ComputecolocalizationparametersTab, 'state');
            app.Button.Icon = 'Logo2.png';
            app.Button.Text = '';
            app.SpearmancoefficientCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.SpearmancoefficientCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpearmancoefficientCheckBoxValueChanged, true);
            app.SpearmancoefficientCheckBox.Text = 'Spearman coefficient';
            app.HelpopenmanualButton = uibutton(app.ComputecolocalizationparametersTab, 'push');
            app.HelpopenmanualButton.ButtonPushedFcn = createCallbackFcn(app, @HelpopenmanualButtonPushed, true);
            app.HelpopenmanualButton.Text = 'Help: open manual';
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox = uicheckbox(app.ComputecolocalizationparametersTab);
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpearmancoefficientfornonzeroinbothchannelspixelsCheckBoxValueChanged, true);
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox.Text = 'Spearman coefficient for "non-zero" in both channels pixels';
            app.RunrandomspotshufflingtestsTab = uitab(app.TabGroup);
            app.RunrandomspotshufflingtestsTab.Title = 'Run random spot shuffling tests';
            app.RunButton_2 = uibutton(app.RunrandomspotshufflingtestsTab, 'state');
            app.RunButton_2.ValueChangedFcn = createCallbackFcn(app, @RunButton_2ValueChanged, true);
            app.RunButton_2.Text = 'Run!';
            app.DistancetoclosestneighbourCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.DistancetoclosestneighbourCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DistancetoclosestneighbourCheckBox_2ValueChanged, true);
            app.DistancetoclosestneighbourCheckBox_2.Text = 'Distance to closest neighbour';
            app.PearsonsCoefficientCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.PearsonsCoefficientCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PearsonsCoefficientCheckBox_2ValueChanged, true);
            app.PearsonsCoefficientCheckBox_2.Text = 'Pearson''s Coefficient';
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged, true);
            app.PearsonsCoefficientfornonzeroinbothchannelspixelsCheckBox_2.Text = 'Pearson''s Coefficient for "non-zero" in both channels pixels';
            app.ManderscoefficientsCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.ManderscoefficientsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ManderscoefficientsCheckBox_2ValueChanged, true);
            app.ManderscoefficientsCheckBox_2.Text = 'Manders coefficients';
            app.Button_3 = uibutton(app.RunrandomspotshufflingtestsTab, 'state');
            app.Button_3.Icon = 'Logo2.png';
            app.Button_3.Text = '';
            app.FolderwithImagesEditField_2Label = uilabel(app.RunrandomspotshufflingtestsTab);
            app.FolderwithImagesEditField_2Label.Text = 'Folder with Images';
            app.FolderwithImagesEditField_2 = uieditfield(app.RunrandomspotshufflingtestsTab, 'text');
            app.FolderwithImagesEditField_2.ValueChangedFcn = createCallbackFcn(app, @FolderwithImagesEditField_2ValueChanged, true);
            app.SavefilteredimagesEditField_2Label = uilabel(app.RunrandomspotshufflingtestsTab);
            app.SavefilteredimagesEditField_2Label.Text = 'Save filtered images';
            app.SavefilteredimagesEditField_2 = uieditfield(app.RunrandomspotshufflingtestsTab, 'text');
            app.SavefilteredimagesEditField_2.ValueChangedFcn = createCallbackFcn(app, @SavefilteredimagesEditField_2ValueChanged, true);
            app.Thresholdvalueformanual01Label = uilabel(app.RunrandomspotshufflingtestsTab);
            app.Thresholdvalueformanual01Label.Text = 'Threshold value for manual [0-1]';
            app.Thresholdvalueformanual01Spinner_2 = uispinner(app.RunrandomspotshufflingtestsTab);
            app.Thresholdvalueformanual01Spinner_2.Step = 0.01;
            app.Thresholdvalueformanual01Spinner_2.Limits = [0 1];
            app.Thresholdvalueformanual01Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Thresholdvalueformanual01Spinner_2ValueChanged, true);
            app.FolderforsavingresultsforrandomshuffledimagesEditFieldLabel = uilabel(app.RunrandomspotshufflingtestsTab);
            app.FolderforsavingresultsforrandomshuffledimagesEditFieldLabel.Text = 'Folder for saving results for random shuffled images';
            app.FolderforsavingresultsforrandomshuffledimagesEditField = uieditfield(app.RunrandomspotshufflingtestsTab, 'text');
            app.FolderforsavingresultsforrandomshuffledimagesEditField.ValueChangedFcn = createCallbackFcn(app, @FolderforsavingresultsforrandomshuffledimagesEditFieldValueChanged, true);
            app.SaveimagesafterrandomspotshufflingEditFieldLabel = uilabel(app.RunrandomspotshufflingtestsTab);
            app.SaveimagesafterrandomspotshufflingEditFieldLabel.Text = 'Save images after random spot shuffling';
            app.SaveimagesafterrandomspotshufflingEditField = uieditfield(app.RunrandomspotshufflingtestsTab, 'text');
            app.SaveimagesafterrandomspotshufflingEditField.ValueChangedFcn = createCallbackFcn(app, @SaveimagesafterrandomspotshufflingEditFieldValueChanged, true);
            app.NumberofrandomtestsSpinnerLabel = uilabel(app.RunrandomspotshufflingtestsTab);
            app.NumberofrandomtestsSpinnerLabel.Text = 'Number of random tests';
            app.NumberofrandomtestsSpinner = uispinner(app.RunrandomspotshufflingtestsTab);
            app.NumberofrandomtestsSpinner.Limits = [1 Inf];
            app.NumberofrandomtestsSpinner.RoundFractionalValues = 'on';
            app.NumberofrandomtestsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofrandomtestsSpinnerValueChanged, true);
            app.NumberofrandomtestsSpinner.Value = 1;
            app.FilteringmethodListBox_2Label = uilabel(app.RunrandomspotshufflingtestsTab);
            app.FilteringmethodListBox_2Label.Text = 'Filtering method';
            app.FilteringmethodListBox_2 = uilistbox(app.RunrandomspotshufflingtestsTab);
            app.FilteringmethodListBox_2.Items = {'Manual', 'Otsu''s', 'Costes', 'Median', 'Median + Otsu''s', 'No filtering'};
            app.FilteringmethodListBox_2.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodListBox_2ValueChanged, true);
            app.FilteringmethodListBox_2.Value = 'Manual';
            app.SpearmancoefficientCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.SpearmancoefficientCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SpearmancoefficientCheckBox_2ValueChanged, true);
            app.SpearmancoefficientCheckBox_2.Text = 'Spearman coefficient';
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2 = uicheckbox(app.RunrandomspotshufflingtestsTab);
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2ValueChanged, true);
            app.SpearmancoefficientfornonzeroinbothchannelspixelsCheckBox_2.Text = 'Spearman coefficient for "non-zero" in both channels pixels';
            app.AdvancedparametersTab = uitab(app.TabGroup);
            app.AdvancedparametersTab.Title = 'Advanced parameters';
            app.Channel11red2green3blueLabel = uilabel(app.AdvancedparametersTab);
            app.Channel11red2green3blueLabel.Text = 'Channel 1 [1 - red, 2 - green, 3 - blue]';
            app.Channel11red2green3blueEditField = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channel11red2green3blueEditField.Limits = [1 3];
            app.Channel11red2green3blueEditField.RoundFractionalValues = 'on';
            app.Channel11red2green3blueEditField.ValueChangedFcn = createCallbackFcn(app, @Channel11red2green3blueEditFieldValueChanged, true);
            app.Channel11red2green3blueEditField.Value = 1;
            app.Channel21red2green3blueEditFieldLabel = uilabel(app.AdvancedparametersTab);
            app.Channel21red2green3blueEditFieldLabel.Text = 'Channel 2 [1 - red, 2 - green, 3 - blue]';
            app.Channel21red2green3blueEditField = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channel21red2green3blueEditField.Limits = [1 3];
            app.Channel21red2green3blueEditField.RoundFractionalValues = 'on';
            app.Channel21red2green3blueEditField.ValueChangedFcn = createCallbackFcn(app, @Channel21red2green3blueEditFieldValueChanged, true);
            app.Channel21red2green3blueEditField.Value = 2;
            app.SizeoftheaveragingareamedianfilteringpxEditFieldLabel = uilabel(app.AdvancedparametersTab);
            app.SizeoftheaveragingareamedianfilteringpxEditFieldLabel.Text = 'Size of the averaging area median filtering [px]';
            app.SizeoftheaveragingareamedianfilteringpxEditField = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.SizeoftheaveragingareamedianfilteringpxEditField.Limits = [0 Inf];
            app.SizeoftheaveragingareamedianfilteringpxEditField.RoundFractionalValues = 'on';
            app.SizeoftheaveragingareamedianfilteringpxEditField.ValueChangedFcn = createCallbackFcn(app, @SizeoftheaveragingareamedianfilteringpxEditFieldValueChanged, true);
            app.SizeoftheaveragingareamedianfilteringpxEditField.Value = 20;
            app.PixelsizenmEditFieldLabel = uilabel(app.AdvancedparametersTab);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size [nm]';
            app.PixelsizenmEditField = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.PixelsizenmEditField.Limits = [0 Inf];
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizenmEditFieldValueChanged, true);
            app.PixelsizenmEditField.Value = 10;
            app.Button_2 = uibutton(app.AdvancedparametersTab, 'state');
            app.Button_2.Icon = 'Logo2.png';
            app.Button_2.Text = '';
            app.PixelsizeinnmEditFieldLabel_2 = uilabel(app.AdvancedparametersTab);
            app.PixelsizeinnmEditFieldLabel_2.Text = 'Only for random tests:';
            app.Channeltodeterminethecellboundary1red2green3blueEditFieldLabel = uilabel(app.AdvancedparametersTab);
            app.Channeltodeterminethecellboundary1red2green3blueEditFieldLabel.Text = 'Channel to determine the cell boundary [1 - red, 2 - green, 3 - blue]';
            app.Channeltodeterminethecellboundary1red2green3blueEditField = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channeltodeterminethecellboundary1red2green3blueEditField.Limits = [1 3];
            app.Channeltodeterminethecellboundary1red2green3blueEditField.RoundFractionalValues = 'on';
            app.Channeltodeterminethecellboundary1red2green3blueEditField.ValueChangedFcn = createCallbackFcn(app, @Channeltodeterminethecellboundary1red2green3blueEditFieldValueChanged, true);
            app.Channeltodeterminethecellboundary1red2green3blueEditField.Value = 1;
            app.LengthoftheboxsidefordeterminingcellboundarypxLabel = uilabel(app.AdvancedparametersTab);
            app.LengthoftheboxsidefordeterminingcellboundarypxLabel.Text = 'Length of the box side for determining cell boundary  [px]';
            app.Channeltodeterminethecellboundary1red2green3blueEditField_2 = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channeltodeterminethecellboundary1red2green3blueEditField_2.Limits = [1 Inf];
            app.Channeltodeterminethecellboundary1red2green3blueEditField_2.RoundFractionalValues = 'on';
            app.Channeltodeterminethecellboundary1red2green3blueEditField_2.ValueChangedFcn = createCallbackFcn(app, @Channeltodeterminethecellboundary1red2green3blueEditField_2ValueChanged, true);
            app.Channeltodeterminethecellboundary1red2green3blueEditField_2.Value = 300;
            app.Densitycorrectionfactorforshuffledchannel02Label = uilabel(app.AdvancedparametersTab);
            app.Densitycorrectionfactorforshuffledchannel02Label.Text = 'Density correction factor for shuffled channel [0,2]';
            app.Channeltodeterminethecellboundary1red2green3blueEditField_3 = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channeltodeterminethecellboundary1red2green3blueEditField_3.Limits = [0 2];
            app.Channeltodeterminethecellboundary1red2green3blueEditField_3.RoundFractionalValues = 'on';
            app.Channeltodeterminethecellboundary1red2green3blueEditField_3.ValueChangedFcn = createCallbackFcn(app, @Channeltodeterminethecellboundary1red2green3blueEditField_3ValueChanged, true);
            app.Channeltodeterminethecellboundary1red2green3blueEditField_3.Value = 1;
            app.Thresholdforthedensityofthespotstodeterminecellboundary01Label = uilabel(app.AdvancedparametersTab);
            app.Thresholdforthedensityofthespotstodeterminecellboundary01Label.Text = 'Threshold for the density of the spots to determine cell boundary [0,1]';
            app.Channeltodeterminethecellboundary1red2green3blueEditField_4 = uieditfield(app.AdvancedparametersTab, 'numeric');
            app.Channeltodeterminethecellboundary1red2green3blueEditField_4.Limits = [0 1];
            app.Channeltodeterminethecellboundary1red2green3blueEditField_4.ValueChangedFcn = createCallbackFcn(app, @Channeltodeterminethecellboundary1red2green3blueEditField_4ValueChanged, true);
            app.Channeltodeterminethecellboundary1red2green3blueEditField_4.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end